classdef PathPlanningWorkbench < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        WorkspaceTab                    matlab.ui.container.Tab
        WorkspaceGrid                   matlab.ui.container.GridLayout
        MetricsPanel                    matlab.ui.container.Panel
        MetricsGrid                     matlab.ui.container.GridLayout
        AlgorithmTimeValueLabel         matlab.ui.control.Label
        AlgorithmTimeLabel              matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        SmoothnessLabel                 matlab.ui.control.Label
        PathLengthLabel                 matlab.ui.control.Label
        TotalTimetakenLabel             matlab.ui.control.Label
        SuccessfulLabel                 matlab.ui.control.Label
        SmoothnessValueLabel            matlab.ui.control.Label
        PathLenValueLabel               matlab.ui.control.Label
        TotalTimeValueLabel             matlab.ui.control.Label
        SuccessValueLabel               matlab.ui.control.Label
        AlgoSelectionPanel              matlab.ui.container.Panel
        ControlPanels                   matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ControlPanel                    matlab.ui.container.Panel
        ControlPanelGrid                matlab.ui.container.GridLayout
        PickHintLabel                   matlab.ui.control.Label
        AlgoDropdown                    matlab.ui.control.DropDown
        SelectAlgorithmLabel            matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        GoalLabel                       matlab.ui.control.Label
        PickGoalToggle                  matlab.ui.control.StateButton
        PickStartToggle                 matlab.ui.control.StateButton
        ClearPointsButton               matlab.ui.control.Button
        WorkspaceAxes                   matlab.ui.control.UIAxes
        MapTab                          matlab.ui.container.Tab
        MapGrid                         matlab.ui.container.GridLayout
        UseThisMapButton                matlab.ui.control.Button
        MapChoicePanel                  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        UploadButton                    matlab.ui.control.Button
        PresetMap3Button                matlab.ui.control.Button
        PresetMap2Button                matlab.ui.control.Button
        PresetMap1Button                matlab.ui.control.Button
        MapPreviewAxes                  matlab.ui.control.UIAxes
        ConfigTab                       matlab.ui.container.Tab
        ConfigTabGrid                   matlab.ui.container.GridLayout
        RobotPanel                      matlab.ui.container.Panel
        RobotPanelGrid                  matlab.ui.container.GridLayout
        RobotColorColorPicker           matlab.ui.control.ColorPicker
        RobotColorColorPickerLabel      matlab.ui.control.Label
        RobotSizeEditField              matlab.ui.control.NumericEditField
        RobotSizeEditFieldLabel         matlab.ui.control.Label
        RobotShapeDropdown              matlab.ui.control.DropDown
        RobotShapeLabel                 matlab.ui.control.Label
        ExportSettingsPanel             matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        RecordEnableCheck               matlab.ui.control.CheckBox
        RecordFPSNumericEditField       matlab.ui.control.NumericEditField
        RecordFPSEditFieldLabel         matlab.ui.control.Label
        RecordFormatDropDown            matlab.ui.control.DropDown
        RecordasDropDownLabel           matlab.ui.control.Label
        DynEnabledCheck                 matlab.ui.control.CheckBox
        DynPanel                        matlab.ui.container.Panel
        DynPanelGrid                    matlab.ui.container.GridLayout
        DeleteObstacleButton            matlab.ui.control.Button
        AddObstacleButton               matlab.ui.control.Button
        ObstaclesTable                  matlab.ui.control.Table
        AlgoPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        DijkstraPanel                   matlab.ui.container.Panel
        DijkstraPanelGrid               matlab.ui.container.GridLayout
        DijkstraDiagFieldEditField      matlab.ui.control.NumericEditField
        DijkstraDiagFieldEditFieldLabel  matlab.ui.control.Label
        DijkstraStraightFieldEditField  matlab.ui.control.NumericEditField
        DijkstraStraightFieldEditFieldLabel  matlab.ui.control.Label
        RRTPanel                        matlab.ui.container.Panel
        RRTPanelGrid                    matlab.ui.container.GridLayout
        SmoothMaxDistSlider             matlab.ui.control.Slider
        SmoothMaxDistSliderLabel        matlab.ui.control.Label
        GoalRadiusSlider                matlab.ui.control.Slider
        GoalRadiusSliderLabel           matlab.ui.control.Label
        GoalBiasSlider                  matlab.ui.control.Slider
        GoalBiasSliderLabel             matlab.ui.control.Label
        StepSizeSlider                  matlab.ui.control.Slider
        StepSizeSliderLabel             matlab.ui.control.Label
        MaxItersEditField               matlab.ui.control.NumericEditField
        MaxItersEditFieldLabel          matlab.ui.control.Label
        PFPanel                         matlab.ui.container.Panel
        PFPanelGrid                     matlab.ui.container.GridLayout
        SmoothMaxDistSlider_PF          matlab.ui.control.Slider
        SmoothMaxDistSlider_2Label      matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        QstarInfluenceradiuspxEditField  matlab.ui.control.NumericEditField
        QstarInfluenceradiuspxEditFieldLabel  matlab.ui.control.Label
        RepulsiveGainetaEditField       matlab.ui.control.NumericEditField
        RepulsiveGainetaEditFieldLabel  matlab.ui.control.Label
        AttractiveGainzetaEditField     matlab.ui.control.NumericEditField
        AttractiveGainzetaEditFieldLabel  matlab.ui.control.Label
        AStarPanel                      matlab.ui.container.Panel
        AStarPanelGrid                  matlab.ui.container.GridLayout
        AStarDiagFieldEditField         matlab.ui.control.NumericEditField
        AStarDiagFieldEditFieldLabel    matlab.ui.control.Label
        AStarStraightFieldEditField     matlab.ui.control.NumericEditField
        AStarStraightFieldEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Central state bag (kept minimal for Step 1 — no "mode")
        State struct = struct( ...
            'mapFile',"", ...
            'mapRaw',[], ...
            'start',[], ...
            'goal',[], ...
            'algo',"AStar" )

        % Update these to real files when you have them
        PresetFiles cell = { ...
            "maps/Occupancy_grid_map_1.ppm", ...
            "maps/Map2.png", ...
            "maps/Occupancy_grid_map_1_flipped.ppm" ...
        }
        
        Graphics struct = struct( ...
            'hMapImage',[], 'hStart',[], 'hGoal',[], ...
            'hPath',[], 'hRobot',[], 'hDynObs',{cell(1,0)} )

        LastWorkspaceMapFile string = ""

        % Dynamic obstacle picking state (uses Add button only)
        PickingDyn logical = false
        PickingDynStage string = "none"      % "none" | "start" | "end"
        PickingDynRow double = NaN           % row index being filled

        % Temporary graphics during picking
        % hDynPickStart matlab.graphics.primitive.Line = matlab.graphics.GraphicsPlaceholder
        % hDynPickEnd   matlab.graphics.primitive.Line = matlab.graphics.GraphicsPlaceholder
        % hDynPickLine  matlab.graphics.primitive.Line = matlab.graphics.GraphicsPlaceholder

        hDynPickStart = gobjects(1);
        hDynPickEnd   = gobjects(1);
        hDynPickLine  = gobjects(1);

        PrevPointer char = 'arrow'           % to restore pointer after picking

        LastRun = []  % struct filled after each run
    end
    
    %---------------------- App lifecycle ----------------------%
    methods (Access = private)
        function startup(app)
            % Ensure State struct exists
            if ~isfield(app,'State') || isempty(app.State)
                app.State = struct();
            end

            % Initialize fields if missing
            if ~isfield(app.State,'mapFile')
                app.State.mapFile = app.resolvePreset(1);
            end
            if ~isfield(app.State,'algo')
                app.State.algo = "AStar";
            end
            if ~isfield(app.State,'procOverride')
                app.State.procOverride = false;
            end
            if ~isfield(app.State,'procFile')
                app.State.procFile = '';
            end
            if ~isfield(app.State,'dynEnabled')
                app.State.dynEnabled = false;
            end

            % Apply UI sync
            app.AlgoDropdown.Value = char(app.State.algo);
            app.updateAlgoParamPanel();

            app.DynEnabledCheck.Value = app.State.dynEnabled;
            app.setPanelEnabled(app.DynPanel, app.State.dynEnabled);

            % Load and preview
            app.loadAndPreviewMap(app.State.mapFile);
            app.refreshMapForAlgo();

            % --- Recording defaults + UI sync ---
            if ~isfield(app.State,'recordEnabled'), app.State.recordEnabled = false; end
            if ~isfield(app.State,'recordFormat'),  app.State.recordFormat  = 'MP4'; end
            if ~isfield(app.State,'recordFPS'),     app.State.recordFPS     = 30;    end

            try
                app.RecordEnableCheck.Value = app.State.recordEnabled;
                app.RecordFormatDropDown.Value = app.State.recordFormat;
                app.RecordFPSNumericEditField.Value = app.State.recordFPS;
                app.RecordFormatDropDown.Enable = matlab.lang.OnOffSwitchState(app.State.recordEnabled);
                app.RecordFPSNumericEditField.Enable = matlab.lang.OnOffSwitchState(app.State.recordEnabled);
            catch
                % controls might not exist yet during dev; ignore
            end
        end

        
        %---------------------- Helpers ----------------------%
        function file = resolvePreset(app, idx)
            % Returns preset path if it exists; otherwise uses a placeholder
            if idx <= numel(app.PresetFiles)
                cand = app.PresetFiles{idx};
                if isfile(cand)
                    file = string(cand);
                    return;
                end
            end
            % Fallback: generate a temp placeholder image
            file = app.makePlaceholderIfMissing();
        end

        function file = makePlaceholderIfMissing(~)
            % Creates a simple checkerboard placeholder in tempdir
            tmp = fullfile(tempdir, 'placeholder_map.png');
            if ~isfile(tmp)
                I = checkerboard(16,8,8) > 0.5; % logical grid
                I = uint8(I)*255;
                imwrite(I, tmp);
            end
            file = string(tmp);
        end

        function loadAndPreviewMap(app, file)
            % Loads an image and shows it on MapPreviewAxes (neutral preview)
            try
                img = imread(file);
            catch
                uialert(app.UIFigure, "Could not read image: " + file, "Load error");
                return
            end
            
            % Normalize to grayscale for a clean preview
            if ndims(img) == 3
                imgGray = rgb2gray(img);
            else
                imgGray = img;
            end
            
            % Gentle contrast stretch for visibility
            imgDisp = imadjust(imgGray);
            
            % Cache for later steps
            app.State.mapRaw = imgDisp;
            app.State.mapProc = []; % computed later in Workspace step

            % Draw in preview axes
            ax = app.MapPreviewAxes;
            cla(ax);
            imshow(imgDisp,'Parent',ax);
            axis(ax,'image');  % square pixels
            title(ax, 'Preview');
        end

        function showMapInWorkspace(app)
            if strlength(app.State.mapFile)==0, return; end
            if isempty(app.State.mapRaw)
                app.loadAndPreviewMap(app.State.mapFile);
                if isempty(app.State.mapRaw), return; end
            end

            ax = app.WorkspaceAxes;
            cla(ax);

            % Draw image and make it non-interactive
            app.Graphics.hMapImage = imshow(app.State.mapRaw, 'Parent', ax);
            axis(ax,'image'); title(ax,'Workspace');

            % >>> critical: let clicks pass through to the axes
            set(app.Graphics.hMapImage, 'HitTest','off', 'PickableParts','none');
            set(ax, 'HitTest','on', 'PickableParts','all');

            % Redraw points if any
            if ~isempty(app.State.start), app.plotMarker('start',app.State.start); end
            if ~isempty(app.State.goal),  app.plotMarker('goal',app.State.goal);  end
        end
        
        function plotMarker(app, kind, xy)
            ax = app.WorkspaceAxes;
            switch lower(kind)
                case 'start'
                    if ~isempty(app.Graphics.hStart)&&isvalid(app.Graphics.hStart), delete(app.Graphics.hStart); end
                    app.Graphics.hStart = line(ax, xy(1), xy(2), ...
                        'Marker','*','MarkerSize',10,'LineStyle','none','Color',[0 0.6 0], ...
                        'HitTest','off','PickableParts','none');
                case 'goal'
                    if ~isempty(app.Graphics.hGoal)&&isvalid(app.Graphics.hGoal), delete(app.Graphics.hGoal); end
                    app.Graphics.hGoal = line(ax, xy(1), xy(2), ...
                        'Marker','s','MarkerSize',8,'LineStyle','none','Color',[0.9 0 0], ...
                        'HitTest','off','PickableParts','none');
            end
        end
        
        function drawPath(app, P)
            if isempty(P), return; end
            ax = app.WorkspaceAxes;
            if ~isempty(app.Graphics.hPath)&&isvalid(app.Graphics.hPath)
                set(app.Graphics.hPath,'XData',P(:,1),'YData',P(:,2));
            else
                app.Graphics.hPath = line(ax, P(:,1), P(:,2), 'LineWidth', 2, 'Color', [0 0.4 1], ...
                'HitTest','off','PickableParts','none');
            end
        end

        function updateMetricsPanel(app, R)
            if ~exist('R','var') || ~isfield(R,'success') || isempty(R)
                app.SuccessValueLabel.Text = "—";
                app.TotalTimeValueLabel.Text = "— s";
                app.AlgorithmTimeValueLabel.Text = "— s";
                app.PathLenValueLabel.Text = "—";
                app.SmoothnessValueLabel.Text = "—";
                return;
            end
            app.SuccessValueLabel.Text    = string(R.success);
            app.TotalTimeValueLabel.Text  = sprintf('%.3f s', R.totalTime);
            app.AlgorithmTimeValueLabel.Text  = sprintf('%.4f s', R.algorithmTime);
            app.PathLenValueLabel.Text    = sprintf('%.2f',   R.pathLen);
            app.SmoothnessValueLabel.Text = sprintf('%.2f',   R.smoothness);
        end


        function mode = getModeForAlgo(~, algo)
            switch string(algo)
                case {"AStar","Dijkstra"}
                    mode = "grid";
                case {"RRT","PF"}
                    mode = "occupancy";
                otherwise
                    mode = "grid";
            end
        end

        function refreshMapForAlgo(app)
            if strlength(app.State.mapFile)==0, return; end
            mode = app.getModeForAlgo(app.State.algo);
            % Use own preprocessMap
            [mapProc, img_display] = preprocessMap(app.State.mapFile, mode);
            % cache both
            app.State.mapProc = mapProc;
            app.State.mapRaw  = img_display;   % display image that matches algo’s coordinate frame
            % redraw workspace
            app.showMapInWorkspace();
        end

        function claOverlays(app)
            function del1(h)
                if ~isempty(h) && isgraphics(h) && isvalid(h)
                    delete(h);
                end
            end
            % path & robot
            del1(app.Graphics.hPath);   app.Graphics.hPath  = [];
            del1(app.Graphics.hRobot);  app.Graphics.hRobot = [];

            % dynamic obstacles (cell array of patch handles)
            if isfield(app.Graphics,'hDynObs') && ~isempty(app.Graphics.hDynObs)
                for i = 1:numel(app.Graphics.hDynObs)
                    del1(app.Graphics.hDynObs{i});
                end
                app.Graphics.hDynObs = {};
            end
        end


        function setPanelEnabled(~, panel, tf)
            % Recursively set Enable on all children that support it
            kids = findall(panel, '-property', 'Enable');   % robust: only those with 'Enable'
            for k = 1:numel(kids)
                try
                    kids(k).Enable = matlab.lang.OnOffSwitchState(tf);
                catch
                    % some containers might not support Enable; ignore
                end
            end
            % optional: soften title color when disabled
            try
                if tf, panel.FontColor = [0 0 0]; else, panel.FontColor = [0.5 0.5 0.5]; end
            catch
            end
        end
        
        function updateAlgoParamPanel(app)
            app.AStarPanel.Visible    = 'off';
            app.DijkstraPanel.Visible = 'off';
            app.RRTPanel.Visible      = 'off';
            app.PFPanel.Visible       = 'off';
            switch app.State.algo
                case "AStar",    app.AStarPanel.Visible    = 'on';
                case "Dijkstra", app.DijkstraPanel.Visible = 'on';
                case "RRT",      app.RRTPanel.Visible      = 'on';
                case "PF",       app.PFPanel.Visible       = 'on';
            end
        end

        function [x, y, inside] = clampToImage(~, x, y, ax)
            inside = false;
            hImg = findobj(ax,'Type','Image');
            if isempty(hImg), return; end
            sz   = size(hImg.CData);
            rows = sz(1);  cols = sz(2);
            if isfinite(x) && isfinite(y)
                x = max(1, min(cols, x));
                y = max(1, min(rows, y));
                inside = true;
            end
        end

        function setPickHint(app, txt)
            try
                app.PickHintLabel.Text = string(txt);
            catch
                % no hint label, ignore
            end
        end

        function deleteTempPickGraphics(app)
            if isgraphics(app.hDynPickStart), delete(app.hDynPickStart); end
            if isgraphics(app.hDynPickEnd),   delete(app.hDynPickEnd);   end
            if isgraphics(app.hDynPickLine),  delete(app.hDynPickLine);  end
            app.hDynPickStart = matlab.graphics.GraphicsPlaceholder;
            app.hDynPickEnd   = matlab.graphics.GraphicsPlaceholder;
            app.hDynPickLine  = matlab.graphics.GraphicsPlaceholder;
        end

        function finishDynPicking(app)
            app.PickingDyn      = false;
            app.PickingDynStage = "none";
            app.PickingDynRow   = NaN;

            % Restore UI
            app.UIFigure.Pointer = app.PrevPointer;
            app.setPickHint("");

            try
                app.PickStartToggle.Enable = 'on';
                app.PickGoalToggle.Enable  = 'on';
            catch
            end
        end

        function abortDynPicking(app, removeRowIfIncomplete)
            r = app.PickingDynRow;

            app.PickingDyn      = false;
            app.PickingDynStage = "none";
            app.PickingDynRow   = NaN;

            % Remove row if incomplete
            if removeRowIfIncomplete && ~isnan(r)
                d = app.ObstaclesTable.Data;
                if ~isempty(d) && r <= size(d,1)
                    if any(isnan(d(r,1:4)))
                        d(r,:) = [];
                        app.ObstaclesTable.Data = d;
                    end
                end
            end

            app.deleteTempPickGraphics();

            % Restore UI
            app.UIFigure.Pointer = app.PrevPointer;
            app.setPickHint("");

            try
                app.PickStartToggle.Enable = 'on';
                app.PickGoalToggle.Enable  = 'on';
            catch
            end
        end

        function out = tern(~, cond, a, b)
            if cond, out = a; else, out = b; end
        end

        function s = stripExt(~, p)
            [~,s,~] = fileparts(p);
        end

        function writeJSON(~, filePath, data)
            txt = jsonencode(data, 'PrettyPrint', true);
            fid = fopen(filePath,'w');
            if fid<0
                error('Cannot write %s', filePath);
            end
            fwrite(fid, txt);
            fclose(fid);
        end


        %---------------------- App Designer StartupFcn ----------------------%
        % Code that executes after component creation
        function startupFcn(app)
            startup(app); % delegate to our helper
            app.WorkspaceAxes.ButtonDownFcn = createCallbackFcn(app, @WorkspaceAxesButtonDown, true);
            addpath(fullfile(fileparts(mfilename('fullpath')),'adapters'));
            addpath(fullfile(fileparts(mfilename('fullpath')),'utils'));
            addpath(fileparts(mfilename('fullpath')));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresetMap1Button
        function PresetMap1ButtonPushed(app, event)
            file = app.resolvePreset(1);
            app.State.mapFile = file;
            app.loadAndPreviewMap(file);

            % Display file is the regular preset (already set above)
            % Enable processing override for Preset 1 only:
            app.State.procOverride = true;

            % Point to the hi-detail processing map (same folder as the display map)
            app.State.procFile = 'Occupancy_grid_map_1_1.ppm';

        end

        % Button pushed function: PresetMap2Button
        function PresetMap2ButtonPushed(app, event)
            file = app.resolvePreset(2);
            app.State.mapFile = file;
            app.loadAndPreviewMap(file);
            app.State.procOverride = false;
            app.State.procFile = '';
        end

        % Button pushed function: PresetMap3Button
        function PresetMap3ButtonPushed(app, event)
            file = app.resolvePreset(3);
            app.State.mapFile = file;
            app.loadAndPreviewMap(file);
            app.State.procOverride = false;
            app.State.procFile = '';
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [f,p] = uigetfile({'*.ppm;*.png;*.jpg;*.jpeg;*.bmp','Image files'}, ...
                          'Select map image');
            if isequal(f,0); return; end
            file = string(fullfile(p,f));
            app.State.mapFile = file;
            app.loadAndPreviewMap(file);
            app.State.procOverride = false;
            app.State.procFile = '';
        end

        % Button pushed function: UseThisMapButton
        function UseThisMapButtonPushed(app, event)
            app.MainTabGroup.SelectedTab = app.WorkspaceTab;
            app.refreshMapForAlgo();
        end

        % Value changed function: PickStartToggle
        function PickStartToggleValueChanged(app, event)
            app.deleteTempPickGraphics();
            if app.PickStartToggle.Value
                app.PickGoalToggle.Value = false; % only one active
            end
        end

        % Value changed function: PickGoalToggle
        function PickGoalToggleValueChanged(app, event)
            if app.PickGoalToggle.Value
                app.PickStartToggle.Value = false; % only one active
            end
        end

        % Button pushed function: ClearPointsButton
        function ClearPointsButtonPushed(app, event)
            app.State.start = []; app.State.goal = [];
            app.StartLabel.Text = "Start: —";
            app.GoalLabel.Text  = "Goal: —";
            if ~isempty(app.Graphics.hStart)&&isvalid(app.Graphics.hStart) 
                delete(app.Graphics.hStart); 
            end
            if ~isempty(app.Graphics.hGoal)&&isvalid(app.Graphics.hGoal)   
                delete(app.Graphics.hGoal);   
            end
            app.Graphics.hStart=[]; app.Graphics.hGoal=[];
        end

        % Value changed function: AlgoDropdown
        function AlgoDropdownValueChanged(app, event)
            % Defensive parse in case ItemsData was set as a single comma string
            val = string(app.AlgoDropdown.Value);
            if contains(val, ",")
                % try to recover: pick first token
                toks = split(val, ",");
                val = strtrim(toks(1));
            end
            app.State.algo = val; % "AStar" | "Dijkstra" | "RRT" | "PF"
            refreshMapForAlgo(app);

            % 2) Tell Config tab to show the right parameter panel
            app.updateAlgoParamPanel();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Guards
            if isempty(app.State.mapRaw),   uialert(app.UIFigure,"Select a map.","Missing"); return; end
            if isempty(app.State.start),    uialert(app.UIFigure,"Pick Start.","Missing");   return; end
            if isempty(app.State.goal),     uialert(app.UIFigure,"Pick Goal.","Missing");    return; end
            
            if ~isfield(app.State,'algo') || strlength(app.State.algo)==0
                app.State.algo = "AStar";
            end

            if ~isfield(app.State,'dynEnabled')
                app.State.dynEnabled = logical(app.DynEnabledCheck.Value);
            end

            % Clear old path visuals
            if isfield(app.Graphics,'hPath') && ~isempty(app.Graphics.hPath) && isvalid(app.Graphics.hPath)
                delete(app.Graphics.hPath); app.Graphics.hPath=[];
            end

            algo = string(app.State.algo);

            baseParams = struct();
            baseParams.robotShape = lower(string(app.RobotShapeDropdown.Value));   % "square" | "disk"
            baseParams.robotSize  = app.RobotSizeEditField.Value;
            baseParams.robotColor = app.RobotColorColorPicker.Value;
            baseParams.dynEnabled = app.State.dynEnabled;
            baseParams.dynamicObstaclesUI = [];  % filled only if enabled

            % ===== tell the logger which case this is =====
            baseParams.regionID   = 'R1';   % <-- set per case (R1/R2/R3)
            baseParams.scenarioID = 'A';    % <-- set per case (A/B)

            if baseParams.dynEnabled
                d = app.ObstaclesTable.Data;
                if ~isempty(d)
                    n = size(d,1);
                    dyn = repmat(struct('start',[],'end',[],'size',20,'speed',0.3, ...
                        'hPatch',[],'pos',[],'pathXY',[]), 1, n);
                    for i = 1:n
                        dyn(i).start = [d(i,1), d(i,2)];
                        dyn(i).end   = [d(i,3), d(i,4)];
                        dyn(i).size  = d(i,5);
                        dyn(i).speed = d(i,6);
                        dyn(i).pos   = dyn(i).start;
                    end
                    baseParams.dynamicObstaclesUI = dyn;
                end
            end

            % Pass the UI flag through to the adapter
            baseParams.useProcessingOverride = app.State.procOverride;
            baseParams.processingMapFile     = app.State.procFile;

            % ---- Recording options ----
            rec.enable = false;
            try rec.enable = logical(app.RecordEnableCheck.Value); catch, end
            if rec.enable
                try rec.format = char(app.RecordFormatDropDown.Value); catch, rec.format = 'MP4'; end
                try rec.fps    = app.RecordFPSNumericEditField.Value;  catch, rec.fps    = 30;   end
            else
                rec.format = 'MP4'; rec.fps = 30;
            end
            baseParams.record = rec;


            switch algo
                case "AStar"
                    params = baseParams;
                    params.costStraight = app.AStarStraightFieldEditField.Value;
                    params.costDiagonal = app.AStarDiagFieldEditField.Value;
                    params.dynPosIsXY   = true;   % << assume pos = [row col]; set true if pos is [x y]

                    R = runAStar_app(app.State.mapFile, app.State.start, app.State.goal, params, app.WorkspaceAxes);
                case "Dijkstra"
                    params = baseParams;
                    params.costStraight = app.DijkstraStraightFieldEditField.Value;
                    params.costDiagonal = app.DijkstraDiagFieldEditField.Value;
                    params.dynPosIsXY   = true;   % << assume pos = [row col]; set true if pos is [x y]

                    R = runDijkstra_app(app.State.mapFile, app.State.start, app.State.goal, params, app.WorkspaceAxes);
                case "RRT"
                    params = baseParams;

                    % Read values from your RRT controls
                    params.rrtMaxIterations = max(1, round(app.MaxItersEditField.Value));
                    params.rrtStepSize      = max(1, round(app.StepSizeSlider.Value));
                    params.rrtGoalBias      = max(0, min(1, app.GoalBiasSlider.Value));
                    params.rrtGoalRadius    = max(1, round(app.GoalRadiusSlider.Value));
                    params.rrtMaxSmoothDist = max(1, round(app.SmoothMaxDistSlider.Value));

                    params.dynPosIsXY   = true;   % << assume pos = [row col]; set true if pos is [x y]

                    R = runRRT_app(app.State.mapFile, app.State.start, app.State.goal, params, app.WorkspaceAxes);
                case "PF"
                    params = baseParams;

                    params.pf_k_att   = app.AttractiveGainzetaEditField.Value;
                    params.pf_k_rep   = app.RepulsiveGainetaEditField.Value;
                    params.pf_Qstar   = app.QstarInfluenceradiuspxEditField.Value;
                    params.pf_maxIts  = app.MaxIterationsEditField.Value;
                    params.pf_tol     = app.ToleranceEditField.Value;
                    params.pf_MaxSmoothDist = app.SmoothMaxDistSlider_PF.Value;

                    params.dynPosIsXY   = true;   % << assume pos = [row col]; set true if pos is [x y]

                    R = runPF_app(app.State.mapFile, app.State.start, app.State.goal, params, app.WorkspaceAxes);
                otherwise
                    uialert(app.UIFigure,"Next step will wire this algorithm.","Not implemented");
                    return;
            end

            % keep handles so Reset/Clear can delete them
            if isfield(R,'meta') && isfield(R.meta,'handles')
                if isfield(R.meta.handles,'hRobot'), app.Graphics.hRobot = R.meta.handles.hRobot; end
                if isfield(R.meta.handles,'hPath'),  app.Graphics.hPath  = R.meta.handles.hPath;  end
                if isfield(R.meta.handles,'hDyn'),  app.Graphics.hDynObs = R.meta.handles.hDyn;   end
            end
            
            % Draw final path and update metrics
            if ~isempty(R.pathXY)
                % final path overlay (blue)
                if ~isempty(app.Graphics.hPath) && isvalid(app.Graphics.hPath)
                    set(app.Graphics.hPath,'XData',R.pathXY(:,1),'YData',R.pathXY(:,2));
                else
                    app.Graphics.hPath = line(app.WorkspaceAxes, R.pathXY(:,1), R.pathXY(:,2), ...
                                      'LineWidth',2,'Color',[0 0.4 1], 'HitTest','off','PickableParts','none');
                end
            end
            if ~R.success && isempty(R.pathXY)
                uialert(app.UIFigure, "No path found. Try moving Start/Goal slightly off walls/obstacles.", "A* failed");
            end

            app.updateMetricsPanel(struct( ...
                'success',R.success, ...
                'totalTime',R.totalTime, ...
                'algorithmTime',R.algorithmTime, ...
                'pathLen',R.pathLen, ...
                'smoothness',R.smoothness));

            % ---- Build export payload for Export... button ----
            exportData = struct();
            exportData.algo       = char(app.AlgoDropdown.Value);
            exportData.mapDisplay = app.State.mapFile;
            exportData.mapProcess = app.tern(app.State.procOverride, app.State.procFile, app.State.mapFile);
            exportData.robot       = struct( ...
                'shape', lower(string(app.RobotShapeDropdown.Value)), ...
                'size',  app.RobotSizeEditField.Value, ...
                'color', app.RobotColorColorPicker.Value);

            exportData.dynEnabled  = logical(app.DynEnabledCheck.Value);
            exportData.dynTable    = app.ObstaclesTable.Data;   % n×6 [sx sy ex ey size speed]

            % From the adapter result:
            exportData.metrics = struct('success',R.success,'totalTime',R.totalTime,'algorithmTime',R.algorithmTime,'pathLen',R.pathLen,'smoothness',R.smoothness);
            exportData.pathXY  = R.pathXY;                      % Nx2
            exportData.video   = struct('enabled',false,'path','','format','');

            if isfield(R,'meta') && isfield(R.meta,'video') && ~isempty(R.meta.video) ...
                    && isfield(R.meta.video,'enabled') && R.meta.video.enabled
                exportData.video = R.meta.video;                % path, format, enabled
            end

            % Save for Export...
            app.LastRun = exportData;

            % --- Safe param getter (nested function defined at end of RunButtonPushed) ---
            getp = @(S,f,def) local_getp(S,f,def);

            % Algorithm-specific params (robust to missing fields)
            stepSize   = getp(params,'rrtStepSize',       NaN);
            iterations = getp(params,'rrtMaxIterations',  NaN);
            goalBias   = getp(params,'rrtGoalBias',       NaN);

            k_att  = getp(params,'pf_k_att',   NaN);
            k_rep  = getp(params,'pf_k_rep',   NaN);
            qstar  = getp(params,'pf_Qstar',   NaN);   % or 'd0' if you rename it

            seed    = getp(params,'seed',               NaN);
            scenID  = getp(params,'scenarioID',         "A");
            region  = getp(params,'regionID',           "R1");
            thresh  = getp(params,'binarizeThreshold',  NaN);
            replans = getp(params,'replans',            NaN);   % populate if you count them
            % also capture S/G for precise filtering later
            exportData.S = app.State.start; % [x y]
            exportData.G = app.State.goal;  % [x y]

            % >>> Append run summary to CSV <<<
            row = table( ...
                string(exportData.mapDisplay), ...
                string(exportData.mapProcess), ...
                string(exportData.algo), ...
                exportData.metrics.success, ...
                exportData.metrics.totalTime, ...       % TotalTime (wall, incl. animation)
                exportData.metrics.algorithmTime, ...   % AlgoTime (planning only, cumulative)
                exportData.metrics.pathLen, ...
                exportData.metrics.smoothness, ...
                string(exportData.robot.shape), ...
                exportData.robot.size, ...
                string(mat2str(exportData.robot.color)), ...
                exportData.dynEnabled, ...
                size(exportData.dynTable,1), ...
                string(region), string(scenID), seed, ...
                stepSize, iterations, goalBias, k_att, k_rep, qstar, thresh, replans, ...
                exportData.S(1), exportData.S(2), exportData.G(1), exportData.G(2), ...
                'VariableNames', { ...
                'Map','MapProcessed','Algorithm','Success','TotalTime','AlgoTime', ...
                'PathLen','Smoothness','RobotShape','RobotSize','RobotColor', ...
                'DynEnabled','DynCount','Region','ScenarioID','Seed', ...
                'RRT_Step','RRT_Iter','RRT_GoalBias','PF_k_att','PF_k_rep','PF_Qstar', ...
                'Thresh','Replans' ...
                'StartX','StartY','GoalX','GoalY' ...
                } ...
                );

            csvDir  = fullfile(pwd,'results');
            csvFile = fullfile(csvDir,'metrics.csv');
            if ~isfolder(csvDir), mkdir(csvDir); end
            if ~isfile(csvFile)
                writetable(row, csvFile);
            else
                writetable(row, csvFile, 'WriteMode','Append');
            end

            % ---- local getter (put this at the very END of RunButtonPushed, as a nested function) ----
            function v = local_getp(S,f,def)
                if isstruct(S) && isfield(S,f) && ~isempty(S.(f))
                    v = S.(f);
                else
                    v = def;
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ClearPointsButtonPushed();   % clears Start/Goal labels & markers
            app.claOverlays();               % <<< new helper below
            app.updateMetricsPanel(struct());% blank metrics
            refreshMapForAlgo(app);
        end

        % Button down function: WorkspaceAxes
        function WorkspaceAxesButtonDown(app, event)
            % ---- Dynamic obstacle picking (priority over robot start/goal) ----
            if app.PickingDyn
                % Get click in axes data coords
                cp = app.WorkspaceAxes.CurrentPoint;
                x = cp(1,1);  y = cp(1,2);

                % Clamp to image bounds
                [x, y, inside] = app.clampToImage(round(x), round(y), app.WorkspaceAxes);
                if ~inside
                    app.setPickHint("Click inside the map image.");
                    return;
                end

                switch app.PickingDynStage
                    case "start"
                        % Clear stale graphics
                        app.deleteTempPickGraphics();

                        % Draw green marker at start
                        app.hDynPickStart = line(app.WorkspaceAxes, x, y, ...
                            'Marker','o','MarkerSize',8,'Color',[0 0.8 0], ...
                            'LineStyle','none','HitTest','off','PickableParts','none');

                        % Write startX/startY to table
                        r = app.PickingDynRow;
                        d = app.ObstaclesTable.Data;
                        d(r,1:2) = [x y];
                        app.ObstaclesTable.Data = d;

                        % Advance to end stage
                        app.PickingDynStage = "end";
                        app.setPickHint("Now click END for obstacle #" + string(r));
                        return;

                    case "end"
                        % Draw red marker at end
                        app.hDynPickEnd = line(app.WorkspaceAxes, x, y, ...
                            'Marker','s','MarkerSize',8,'Color',[0.9 0 0], ...
                            'LineStyle','none','HitTest','off','PickableParts','none');

                        % Preview line
                        r  = app.PickingDynRow;
                        d  = app.ObstaclesTable.Data;
                        sx = d(r,1); sy = d(r,2);
                        app.hDynPickLine = line(app.WorkspaceAxes, [sx x], [sy y], ...
                            'LineWidth',1.0,'Color',[0.2 0.6 1 0.6], ...
                            'HitTest','off','PickableParts','none');

                        % Write endX/endY
                        d(r,3:4) = [x y];
                        app.ObstaclesTable.Data = d;

                        % Finish picking
                        app.setPickHint("Obstacle #" + string(r) + " set. Edit size/speed if needed.");
                        app.finishDynPicking();
                        return;
                end
            end

            % ---- Existing robot start/goal picking ----
            ax = app.WorkspaceAxes;
            pt = ax.CurrentPoint(1,1:2);
            
            if isempty(app.State.mapRaw), return; end
            [h,w] = size(app.State.mapRaw);
            x = pt(1); y = pt(2);
            if x<0.5 || x>w+0.5 || y<0.5 || y>h+0.5, return; end

            if app.PickStartToggle.Value
                app.State.start = [x y];
                app.plotMarker('start',[x y]);
                app.StartLabel.Text = sprintf('Start: (%.1f, %.1f)', x, y);
            elseif app.PickGoalToggle.Value
                app.State.goal = [x y];
                app.plotMarker('goal',[x y]);
                app.GoalLabel.Text = sprintf('Goal: (%.1f, %.1f)', x, y);
            end    
        end

        % Value changed function: DynEnabledCheck
        function DynEnabledCheckValueChanged(app, event)
            app.State.dynEnabled = logical(app.DynEnabledCheck.Value);
            app.DynPanel.Visible = 'on';
            app.setPanelEnabled(app.DynPanel, app.State.dynEnabled);
        end

        % Button pushed function: AddObstacleButton
        function AddObstacleButtonPushed(app, event)
            % If we're already in pick mode, pressing Add will CANCEL the pick
            if app.PickingDyn
                app.abortDynPicking(true);   % remove incomplete row
                return;
            end

            % Require a map image on the Workspace axes
            hImg = findobj(app.WorkspaceAxes,'Type','Image');
            if isempty(hImg)
                uialert(app.UIFigure, 'Select a map and show it on the Workspace tab first.', 'No map');
                return;
            end

            % Append a new row with NaN coords and default size/speed
            d = app.ObstaclesTable.Data;
            if isempty(d), d = zeros(0,6); end
            newRow = [NaN NaN NaN NaN 20 0.30];   % [startX startY endX endY size speed]
            d = [d; newRow];
            app.ObstaclesTable.Data = d;

            % Enter picking mode for that row
            app.PickingDynRow   = size(app.ObstaclesTable.Data,1);
            app.PickingDyn      = true;
            app.PickingDynStage = "start";

            % UI affordances
            app.PrevPointer      = app.UIFigure.Pointer;
            app.UIFigure.Pointer = 'crosshair';
            app.setPickHint("Click START on the map for obstacle #" + string(app.PickingDynRow));

            % Optional: avoid conflicts with robot pick toggles
            try
                app.PickStartToggle.Enable = 'off';
                app.PickGoalToggle.Enable  = 'off';
            catch
            end

            % Bring the Workspace tab to front so user can click
            app.MainTabGroup.SelectedTab = app.WorkspaceTab;
            drawnow;
        end

        % Button pushed function: DeleteObstacleButton
        function DeleteObstacleButtonPushed(app, event)
            d = app.ObstaclesTable.Data;
            if isempty(d), return; end
            idx = app.ObstaclesTable.Selection;
            if isempty(idx), d(end,:) = []; else, d(idx(1),:) = []; end
            app.ObstaclesTable.Data = d;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.LastRun) || ~isfield(app.LastRun,'pathXY') || isempty(app.LastRun.pathXY)
                uialert(app.UIFigure,'Run a path first.','Nothing to export');
                return;
            end

            % Choose destination folder
            dest = uigetdir(pwd,'Choose export folder');
            if isequal(dest,0), return; end

            % Base name = <algo>_<map>_<YYYYMMDD_HHMMSS>
            ts   = datestr(now,'yyyymmdd_HHMMSS');
            base = sprintf('%s_%s_%s', app.LastRun.algo, app.stripExt(app.LastRun.mapDisplay), ts);

            % 1) Snapshot PNG of current WorkspaceAxes
            pngPath = fullfile(dest, [base '_snapshot.png']);
            exportgraphics(app.WorkspaceAxes, pngPath, 'BackgroundColor','current', 'ContentType','image', 'Resolution', 200);

            % 2) Path CSV (x,y)
            csvPath = fullfile(dest, [base '_path.csv']);
            writematrix(app.LastRun.pathXY, csvPath);

            % 3) Metrics JSON
            metricsPath = fullfile(dest, [base '_metrics.json']);
            app.writeJSON(metricsPath, app.LastRun.metrics);

            % 4) Params JSON (algo/robot/dynamic/map info)
            params = rmfield(app.LastRun, {'metrics','pathXY','video'}); % keep params separate
            paramsPath = fullfile(dest, [base '_params.json']);
            app.writeJSON(paramsPath, params);

            % 5) Video MP4/GIF (copy from temp path produced during run)
            videoMsg = 'No video (recording disabled).';
            if isfield(app.LastRun,'video') && app.LastRun.video.enabled && ~isempty(app.LastRun.video.path)
                [~,~,ext] = fileparts(app.LastRun.video.path);
                videoPath = fullfile(dest, [base '_video' ext]);
                copyfile(app.LastRun.video.path, videoPath);
                videoMsg = videoPath;
            end

            uialert(app.UIFigure, "Exported:\n" + strjoin({pngPath,csvPath,metricsPath,paramsPath,videoMsg} , "\n"), ...
                'Export complete', 'Icon','success');
        end

        % Value changed function: RecordEnableCheck
        function RecordEnableCheckValueChanged(app, event)
            val = logical(app.RecordEnableCheck.Value);
            app.State.recordEnabled = val;
            app.RecordFormatDropDown.Enable = matlab.lang.OnOffSwitchState(val);
            app.RecordFPSNumericEditField.Enable = matlab.lang.OnOffSwitchState(val);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.WorkspaceTab = uitab(app.MainTabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            app.WorkspaceGrid = uigridlayout(app.WorkspaceTab);
            app.WorkspaceAxes = uiaxes(app.WorkspaceGrid);
            title(app.WorkspaceAxes, 'Workspace')
            app.WorkspaceAxes.XTick = [];
            app.WorkspaceAxes.YTick = [];
            app.WorkspaceAxes.ZTick = [];
            app.WorkspaceAxes.ButtonDownFcn = createCallbackFcn(app, @WorkspaceAxesButtonDown, true);
            app.ControlPanel = uipanel(app.WorkspaceGrid);
            app.ControlPanel.Title = 'Controls';
            app.ControlPanelGrid = uigridlayout(app.ControlPanel);
            app.ClearPointsButton = uibutton(app.ControlPanelGrid, 'push');
            app.ClearPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButtonPushed, true);
            app.ClearPointsButton.Text = 'Clear Points';
            app.PickStartToggle = uibutton(app.ControlPanelGrid, 'state');
            app.PickStartToggle.ValueChangedFcn = createCallbackFcn(app, @PickStartToggleValueChanged, true);
            app.PickStartToggle.Text = 'Pick Start';
            app.PickGoalToggle = uibutton(app.ControlPanelGrid, 'state');
            app.PickGoalToggle.ValueChangedFcn = createCallbackFcn(app, @PickGoalToggleValueChanged, true);
            app.PickGoalToggle.Text = 'Pick Goal';
            app.GoalLabel = uilabel(app.ControlPanelGrid);
            app.GoalLabel.Text = 'Goal: —';
            app.StartLabel = uilabel(app.ControlPanelGrid);
            app.StartLabel.Text = 'Start: —';
            app.SelectAlgorithmLabel = uilabel(app.ControlPanelGrid);
            app.SelectAlgorithmLabel.Text = 'Select Algorithm';
            app.AlgoDropdown = uidropdown(app.ControlPanelGrid);
            app.AlgoDropdown.Items = {'A*', 'Dijkstra', 'RRT', 'Potential Fields'};
            app.AlgoDropdown.ItemsData = {'AStar', 'Dijkstra', 'RRT', 'PF'};
            app.AlgoDropdown.ValueChangedFcn = createCallbackFcn(app, @AlgoDropdownValueChanged, true);
            app.AlgoDropdown.Value = 'AStar';
            app.PickHintLabel = uilabel(app.ControlPanelGrid);
            app.PickHintLabel.Text = '';
            app.AlgoSelectionPanel = uipanel(app.WorkspaceGrid);
            app.ControlPanels = uigridlayout(app.AlgoSelectionPanel);
            app.ResetButton = uibutton(app.ControlPanels, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunButton = uibutton(app.ControlPanels, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MetricsPanel = uipanel(app.WorkspaceGrid);
            app.MetricsPanel.Title = 'Metrics';
            app.MetricsGrid = uigridlayout(app.MetricsPanel);
            app.SuccessValueLabel = uilabel(app.MetricsGrid);
            app.SuccessValueLabel.Text = '—';
            app.TotalTimeValueLabel = uilabel(app.MetricsGrid);
            app.TotalTimeValueLabel.Text = '— s';
            app.PathLenValueLabel = uilabel(app.MetricsGrid);
            app.PathLenValueLabel.Text = '—';
            app.SmoothnessValueLabel = uilabel(app.MetricsGrid);
            app.SmoothnessValueLabel.Text = '—';
            app.SuccessfulLabel = uilabel(app.MetricsGrid);
            app.SuccessfulLabel.Text = 'Successful?';
            app.TotalTimetakenLabel = uilabel(app.MetricsGrid);
            app.TotalTimetakenLabel.Text = 'Total Time taken';
            app.PathLengthLabel = uilabel(app.MetricsGrid);
            app.PathLengthLabel.Text = 'Path Length';
            app.SmoothnessLabel = uilabel(app.MetricsGrid);
            app.SmoothnessLabel.Text = 'Smoothness';
            app.ExportButton = uibutton(app.MetricsGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.AlgorithmTimeLabel = uilabel(app.MetricsGrid);
            app.AlgorithmTimeLabel.Text = 'Algorithm Run Time';
            app.AlgorithmTimeValueLabel = uilabel(app.MetricsGrid);
            app.AlgorithmTimeValueLabel.Text = '— s';
            app.MapTab = uitab(app.MainTabGroup);
            app.MapTab.Title = 'Map Selection';
            app.MapGrid = uigridlayout(app.MapTab);
            app.MapPreviewAxes = uiaxes(app.MapGrid);
            title(app.MapPreviewAxes, 'Preview')
            app.MapPreviewAxes.XTick = [];
            app.MapPreviewAxes.YTick = [];
            app.MapPreviewAxes.ZTick = [];
            app.MapChoicePanel = uipanel(app.MapGrid);
            app.MapChoicePanel.Title = 'Choose a map';
            app.GridLayout = uigridlayout(app.MapChoicePanel);
            app.PresetMap1Button = uibutton(app.GridLayout, 'push');
            app.PresetMap1Button.ButtonPushedFcn = createCallbackFcn(app, @PresetMap1ButtonPushed, true);
            app.PresetMap1Button.Icon = fullfile(pathToMLAPP, 'maps', 'Occupancy_grid_map_1.png');
            app.PresetMap1Button.Text = 'Preset Map 1';
            app.PresetMap2Button = uibutton(app.GridLayout, 'push');
            app.PresetMap2Button.ButtonPushedFcn = createCallbackFcn(app, @PresetMap2ButtonPushed, true);
            app.PresetMap2Button.Icon = fullfile(pathToMLAPP, 'maps', 'Map2.png');
            app.PresetMap2Button.Text = 'Preset Map 2';
            app.PresetMap3Button = uibutton(app.GridLayout, 'push');
            app.PresetMap3Button.ButtonPushedFcn = createCallbackFcn(app, @PresetMap3ButtonPushed, true);
            app.PresetMap3Button.Icon = fullfile(pathToMLAPP, 'maps', 'Occupancy_grid_map_1_flipped.png');
            app.PresetMap3Button.Text = 'Preset Map 3';
            app.UploadButton = uibutton(app.GridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = '+ Upload…';
            app.UseThisMapButton = uibutton(app.MapGrid, 'push');
            app.UseThisMapButton.ButtonPushedFcn = createCallbackFcn(app, @UseThisMapButtonPushed, true);
            app.UseThisMapButton.Text = 'Use this map →';
            app.ConfigTab = uitab(app.MainTabGroup);
            app.ConfigTab.Title = 'Config';
            app.ConfigTabGrid = uigridlayout(app.ConfigTab);
            app.AlgoPanel = uipanel(app.ConfigTabGrid);
            app.AlgoPanel.Title = 'Algorithm Parameters';
            app.GridLayout3 = uigridlayout(app.AlgoPanel);
            app.AStarPanel = uipanel(app.GridLayout3);
            app.AStarPanel.Title = 'AStar';
            app.AStarPanelGrid = uigridlayout(app.AStarPanel);
            app.AStarStraightFieldEditFieldLabel = uilabel(app.AStarPanelGrid);
            app.AStarStraightFieldEditFieldLabel.Text = 'AStarStraightField';
            app.AStarStraightFieldEditField = uieditfield(app.AStarPanelGrid, 'numeric');
            app.AStarStraightFieldEditField.Value = 10;
            app.AStarDiagFieldEditFieldLabel = uilabel(app.AStarPanelGrid);
            app.AStarDiagFieldEditFieldLabel.Text = 'AStarDiagField';
            app.AStarDiagFieldEditField = uieditfield(app.AStarPanelGrid, 'numeric');
            app.AStarDiagFieldEditField.Value = 14;
            app.PFPanel = uipanel(app.GridLayout3);
            app.PFPanel.Title = 'PF';
            app.PFPanel.Visible = 'off';
            app.PFPanelGrid = uigridlayout(app.PFPanel);
            app.AttractiveGainzetaEditFieldLabel = uilabel(app.PFPanelGrid);
            app.AttractiveGainzetaEditFieldLabel.Text = 'Attractive Gain (zeta)';
            app.AttractiveGainzetaEditField = uieditfield(app.PFPanelGrid, 'numeric');
            app.AttractiveGainzetaEditField.Limits = [0 0.1];
            app.AttractiveGainzetaEditField.Value = 0.02;
            app.RepulsiveGainetaEditFieldLabel = uilabel(app.PFPanelGrid);
            app.RepulsiveGainetaEditFieldLabel.Text = 'Repulsive Gain (eta)';
            app.RepulsiveGainetaEditField = uieditfield(app.PFPanelGrid, 'numeric');
            app.RepulsiveGainetaEditField.Limits = [1 1000];
            app.RepulsiveGainetaEditField.Value = 70;
            app.QstarInfluenceradiuspxEditFieldLabel = uilabel(app.PFPanelGrid);
            app.QstarInfluenceradiuspxEditFieldLabel.Text = 'Qstar (Influence radius, px)';
            app.QstarInfluenceradiuspxEditField = uieditfield(app.PFPanelGrid, 'numeric');
            app.QstarInfluenceradiuspxEditField.Limits = [1 80];
            app.QstarInfluenceradiuspxEditField.Value = 1.2;
            app.MaxIterationsEditFieldLabel = uilabel(app.PFPanelGrid);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.PFPanelGrid, 'numeric');
            app.MaxIterationsEditField.Limits = [100 20000];
            app.MaxIterationsEditField.Value = 10000;
            app.ToleranceEditFieldLabel = uilabel(app.PFPanelGrid);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.PFPanelGrid, 'numeric');
            app.ToleranceEditField.Limits = [0.5 5];
            app.ToleranceEditField.Value = 1;
            app.SmoothMaxDistSlider_2Label = uilabel(app.PFPanelGrid);
            app.SmoothMaxDistSlider_2Label.Text = 'SmoothMaxDist';
            app.SmoothMaxDistSlider_PF = uislider(app.PFPanelGrid);
            app.SmoothMaxDistSlider_PF.Limits = [5 100];
            app.SmoothMaxDistSlider_PF.MajorTicks = [5 30 60 90];
            app.SmoothMaxDistSlider_PF.Step = 5;
            app.SmoothMaxDistSlider_PF.Value = 30;
            app.RRTPanel = uipanel(app.GridLayout3);
            app.RRTPanel.Title = 'RRT';
            app.RRTPanel.Visible = 'off';
            app.RRTPanelGrid = uigridlayout(app.RRTPanel);
            app.MaxItersEditFieldLabel = uilabel(app.RRTPanelGrid);
            app.MaxItersEditFieldLabel.Text = 'MaxIters';
            app.MaxItersEditField = uieditfield(app.RRTPanelGrid, 'numeric');
            app.MaxItersEditField.Limits = [1 Inf];
            app.MaxItersEditField.Value = 50000;
            app.StepSizeSliderLabel = uilabel(app.RRTPanelGrid);
            app.StepSizeSliderLabel.Text = 'StepSize';
            app.StepSizeSlider = uislider(app.RRTPanelGrid);
            app.StepSizeSlider.Limits = [0 50];
            app.StepSizeSlider.MajorTicks = [0 10 20 30 40 50];
            app.StepSizeSlider.Step = 1;
            app.StepSizeSlider.Value = 5;
            app.GoalBiasSliderLabel = uilabel(app.RRTPanelGrid);
            app.GoalBiasSliderLabel.Text = 'GoalBias';
            app.GoalBiasSlider = uislider(app.RRTPanelGrid);
            app.GoalBiasSlider.Limits = [0 0.3];
            app.GoalBiasSlider.Step = 0.01;
            app.GoalBiasSlider.Value = 0.1;
            app.GoalRadiusSliderLabel = uilabel(app.RRTPanelGrid);
            app.GoalRadiusSliderLabel.Text = 'GoalRadius';
            app.GoalRadiusSlider = uislider(app.RRTPanelGrid);
            app.GoalRadiusSlider.Limits = [1 25];
            app.GoalRadiusSlider.MajorTicks = [1 5 10 15 20 25];
            app.GoalRadiusSlider.Step = 1;
            app.GoalRadiusSlider.Value = 2;
            app.SmoothMaxDistSliderLabel = uilabel(app.RRTPanelGrid);
            app.SmoothMaxDistSliderLabel.Text = 'SmoothMaxDist';
            app.SmoothMaxDistSlider = uislider(app.RRTPanelGrid);
            app.SmoothMaxDistSlider.Limits = [5 100];
            app.SmoothMaxDistSlider.MajorTicks = [5 30 60 90];
            app.SmoothMaxDistSlider.Step = 5;
            app.SmoothMaxDistSlider.Value = 30;
            app.DijkstraPanel = uipanel(app.GridLayout3);
            app.DijkstraPanel.Title = 'Dijkstra';
            app.DijkstraPanel.Visible = 'off';
            app.DijkstraPanelGrid = uigridlayout(app.DijkstraPanel);
            app.DijkstraStraightFieldEditFieldLabel = uilabel(app.DijkstraPanelGrid);
            app.DijkstraStraightFieldEditFieldLabel.Text = 'DijkstraStraightField';
            app.DijkstraStraightFieldEditField = uieditfield(app.DijkstraPanelGrid, 'numeric');
            app.DijkstraStraightFieldEditField.Value = 10;
            app.DijkstraDiagFieldEditFieldLabel = uilabel(app.DijkstraPanelGrid);
            app.DijkstraDiagFieldEditFieldLabel.Text = 'DijkstraDiagField';
            app.DijkstraDiagFieldEditField = uieditfield(app.DijkstraPanelGrid, 'numeric');
            app.DijkstraDiagFieldEditField.Value = 14;
            app.DynPanel = uipanel(app.ConfigTabGrid);
            app.DynPanel.Enable = 'off';
            app.DynPanel.Title = 'Dynamic Obstacles';
            app.DynPanelGrid = uigridlayout(app.DynPanel);
            app.ObstaclesTable = uitable(app.DynPanelGrid);
            app.ObstaclesTable.ColumnName = {'startX'; 'startY'; 'endX'; 'endY'; 'Size'; 'Speed'};
            app.ObstaclesTable.RowName = {};
            app.ObstaclesTable.ColumnEditable = [true true true true true true];
            app.AddObstacleButton = uibutton(app.DynPanelGrid, 'push');
            app.AddObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @AddObstacleButtonPushed, true);
            app.AddObstacleButton.Text = 'Add Obstacle';
            app.DeleteObstacleButton = uibutton(app.DynPanelGrid, 'push');
            app.DeleteObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteObstacleButtonPushed, true);
            app.DeleteObstacleButton.Text = 'Delete Obstacle';
            app.DynEnabledCheck = uicheckbox(app.ConfigTabGrid);
            app.DynEnabledCheck.ValueChangedFcn = createCallbackFcn(app, @DynEnabledCheckValueChanged, true);
            app.DynEnabledCheck.Text = 'Enable Dynamic Obstacles';
            app.ExportSettingsPanel = uipanel(app.ConfigTabGrid);
            app.ExportSettingsPanel.Title = 'Export Settings';
            app.GridLayout4 = uigridlayout(app.ExportSettingsPanel);
            app.RecordasDropDownLabel = uilabel(app.GridLayout4);
            app.RecordasDropDownLabel.Enable = 'off';
            app.RecordasDropDownLabel.Text = 'Record as';
            app.RecordFormatDropDown = uidropdown(app.GridLayout4);
            app.RecordFormatDropDown.Items = {'MP4', 'GIF'};
            app.RecordFormatDropDown.Enable = 'off';
            app.RecordFormatDropDown.Value = 'MP4';
            app.RecordFPSEditFieldLabel = uilabel(app.GridLayout4);
            app.RecordFPSEditFieldLabel.Enable = 'off';
            app.RecordFPSEditFieldLabel.Text = 'Record FPS';
            app.RecordFPSNumericEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RecordFPSNumericEditField.Limits = [1 60];
            app.RecordFPSNumericEditField.Enable = 'off';
            app.RecordFPSNumericEditField.Value = 30;
            app.RecordEnableCheck = uicheckbox(app.GridLayout4);
            app.RecordEnableCheck.ValueChangedFcn = createCallbackFcn(app, @RecordEnableCheckValueChanged, true);
            app.RecordEnableCheck.Text = 'Record as video';
            app.RobotPanel = uipanel(app.ConfigTabGrid);
            app.RobotPanel.Title = 'Robot';
            app.RobotPanelGrid = uigridlayout(app.RobotPanel);
            app.RobotShapeLabel = uilabel(app.RobotPanelGrid);
            app.RobotShapeLabel.Text = 'Robot Shape';
            app.RobotShapeDropdown = uidropdown(app.RobotPanelGrid);
            app.RobotShapeDropdown.Items = {'Square', 'Disk'};
            app.RobotShapeDropdown.ItemsData = {'Square', 'Disk'};
            app.RobotShapeDropdown.Value = 'Square';
            app.RobotSizeEditFieldLabel = uilabel(app.RobotPanelGrid);
            app.RobotSizeEditFieldLabel.Text = 'Robot Size';
            app.RobotSizeEditField = uieditfield(app.RobotPanelGrid, 'numeric');
            app.RobotSizeEditField.Value = 6;
            app.RobotColorColorPickerLabel = uilabel(app.RobotPanelGrid);
            app.RobotColorColorPickerLabel.Text = 'Robot Color';
            app.RobotColorColorPicker = uicolorpicker(app.RobotPanelGrid);
            app.RobotColorColorPicker.Value = [0.4235 0.9569 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathPlanningWorkbench

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end